classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NetlistMenu                  matlab.ui.container.Menu
        OpenMenu                     matlab.ui.container.Menu
        netfileMenu                  matlab.ui.container.Menu
        xlsxfileMenu                 matlab.ui.container.Menu
        ClearMenu                    matlab.ui.container.Menu
        ClearAllMenu                 matlab.ui.container.Menu
        ClearTourchstonefilesMenu    matlab.ui.container.Menu
        ClearNetlistfilesMenu        matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout2                  matlab.ui.container.GridLayout
        TabGroup                     matlab.ui.container.TabGroup
        RectangularTab               matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout6                  matlab.ui.container.GridLayout
        RectPlotStyleDropDown        matlab.ui.control.DropDown
        PlotStyleDropDownLabel       matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        PlotSelectorDropDown         matlab.ui.control.DropDown
        PlotSelectorDropDownLabel    matlab.ui.control.Label
        UIAxesS22                    matlab.ui.control.UIAxes
        UIAxesS21                    matlab.ui.control.UIAxes
        UIAxesS12                    matlab.ui.control.UIAxes
        UIAxesS11                    matlab.ui.control.UIAxes
        PolarTab                     matlab.ui.container.Tab
        GridLayout7                  matlab.ui.container.GridLayout
        GridLayout5_3                matlab.ui.container.GridLayout
        PlotSelectorDropDown_3       matlab.ui.control.DropDown
        PlotSelectorDropDown_3Label  matlab.ui.control.Label
        SmithChartTab                matlab.ui.container.Tab
        GridLayout8                  matlab.ui.container.GridLayout
        S22SmithChartPanel           matlab.ui.container.Panel
        S21SmithChartPanel           matlab.ui.container.Panel
        S12SmithChartPanel           matlab.ui.container.Panel
        S11SmithChartPanel           matlab.ui.container.Panel
        GridLayout5_4                matlab.ui.container.GridLayout
        PlotSelectorDropDown_4       matlab.ui.control.DropDown
        PlotSelectorDropDown_4Label  matlab.ui.control.Label
        CTCTab                       matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        GridLayout10                 matlab.ui.container.GridLayout
        TouchstoneListBox            matlab.ui.control.ListBox
        TouchstoneListBoxLabel       matlab.ui.control.Label
        Netlist2ListBox              matlab.ui.control.ListBox
        Netlist2ListBoxLabel         matlab.ui.control.Label
        Netlist1ListBox              matlab.ui.control.ListBox
        Netlist1ListBoxLabel         matlab.ui.control.Label
        SimulateButton               matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        GridLayout3                  matlab.ui.container.GridLayout
        ListBoxNetlistFiles          matlab.ui.control.ListBox
        NetlistFilesLabel            matlab.ui.control.Label
        ListBoxTouchstoneFiles       matlab.ui.control.ListBox
        TouchstoneFilesLabel         matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        ControlPanel                 matlab.ui.container.Panel
        ZoEditFieldLabel_2           matlab.ui.control.Label
        ZoEditField                  matlab.ui.control.NumericEditField
        ZoEditFieldLabel             matlab.ui.control.Label
        PortnumberEditField          matlab.ui.control.NumericEditField
        PortnumberEditFieldLabel     matlab.ui.control.Label
        TouchtoneFileoptionsPanel    matlab.ui.container.Panel
        FreqUnitDropDown             matlab.ui.control.DropDown
        FreqUnitDropDownLabel        matlab.ui.control.Label
        FormatDropDown               matlab.ui.control.DropDown
        FormatDropDownLabel          matlab.ui.control.Label
        OutputfileEditField          matlab.ui.control.EditField
        OutputfileEditFieldLabel     matlab.ui.control.Label
        InputfileEditField           matlab.ui.control.EditField
        InputfileEditFieldLabel      matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        OpenButton                   matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        DropDownEndFreq              matlab.ui.control.DropDown
        DropDownStartFreq            matlab.ui.control.DropDown
        StepsEditField               matlab.ui.control.NumericEditField
        StepsEditFieldLabel          matlab.ui.control.Label
        EndFreqEditField             matlab.ui.control.NumericEditField
        EndFreqEditFieldLabel        matlab.ui.control.Label
        StartFreqEditField           matlab.ui.control.NumericEditField
        StartFreqEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        net_fileName
        net_filePath
        net_file string {mustBeFile(net_file)} 
        xslx_fileName
        xslx_filePath
        xslx_file string {mustBeFile(xslx_file)} % Full path to the file
        Netlist_table 
        Netlist_CellArray
        ZMatrix
        YMatrix
        ABCD_Matrix
        SMatrix
        Start_Freq_Fld
        End_Freq_Fld
        Start_Freq
        End_Freq
        Start_Freq_Unit
        End_Freq_Unit
        Touchstone_FreqUnit 
        Frequencies
        Step
        Zo
        Port_Num
        Run_Valid = false;
        Warning_Msg
        Parameters_Ready = false;
        Touchstone_Out_File
        Touchstone_Out_File_Name
        Touchstone_Out_File_Path
        Touchstone_Out_File_Format
        Touchstone_Input_File
        Touchstone_Input_File_Name
        Touchstone_Input_File_Path
        SMatrix_Touchstone
        AllRectAxes
        PolAxesS11
        PolAxesS12
        PolAxesS21
        PolAxesS22
        AllPolarAxes
        SmithAxesS11
        SmithAxesS12
        SmithAxesS21
        SmithAxesS22
        AllSmithAxes 
        S_Parameters_Obj
        CTC_Netlist1 
        CTC_Netlist2
        CTC_Touchstone
        Sel_Touchstone
        RectPlotStyle
    end
    
    events (NotifyAccess = private)
        PathChanged 
        xlsxNetlistChanged
    end
    
    methods (Access = private)
        
        function Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num) 
            
            %calculate the number of nodes for the matrix size
            NetlistCASize = size(Netlist_CellArray);
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            AllNodes = unique(allNodesConn,'sorted'); 
            Nodes = AllNodes(2:end); %Remove the reference node N0 (assumed it will always be named as that an therefore be at the top of the arrray)
            NodesNum = length(Nodes); 
            Nodes_Matrix = zeros(NodesNum,NodesNum); %Se crea una matriz de NxN donde N es la cantidad de puertos
            Y_Parameter_Matrix = zeros(Port_Num,Port_Num); %Temp variable, not exacly Y parameter matrix 
            Z_Parameter_Matrix = zeros(Port_Num,Port_Num);
            Z_Matrix = zeros(Port_Num,Port_Num, Step);
            Step_num = 0; 

            if NetlistCASize(1,1) == 1 %Netlist has a single element, therefore a series or shunt element or NodesNum == 2 (including gnd)
                %For file netlist.net ground = 0, while for netlist.xlsx ground = N0
                if (ismember('N0', AllNodes) || ismember('0', AllNodes))
                    for F = Start_Freq:(End_Freq-Start_Freq)/(Step-1):End_Freq
                        Step_num = Step_num + 1;
                        Bl=Netlist_CellArray{1,6};
                        opFreq=Netlist_CellArray{1,7};
                        Z_Matrix(:,:,Step_num) = (Calc_Impedance(app,Netlist_CellArray{1,4},Netlist_CellArray{1,5},F,Bl,opFreq));
                    end
                else
                    Z_Matrix(:,:) = 0; %Series element doesnt have Z param
                end
           
            else  %Netlist has more than 1 row  

                for F = Start_Freq:(End_Freq-Start_Freq)/(Step-1):End_Freq
                    Step_num = Step_num + 1;
                    Nodes_Matrix = Nodes_Matrix_Fun(app,Netlist_CellArray,F); %Nodes_Matrix_fun function
    
                    for i=1 : Port_Num
                        RedRow_Nodes_Matrix = Nodes_Matrix;  %Esta igualacion se usa para guardar la matriz de Nodes_Matrix temporalmente y en la siguiente linea se 
                        RedRow_Nodes_Matrix(i,:) = [];          %elimina un renglon, esto es para crear las matrices reducidas 11, 12,  21, etc.
                    
                        for j = 1 : Port_Num
                            RedCol_Nodes_Matrix = RedRow_Nodes_Matrix;                  
                            RedCol_Nodes_Matrix(:,j) = [];      %lo mismo que en el caso anterior pero para eliminar columnas
                    
                            Y_Parameter_Matrix(i,j) = (det(Nodes_Matrix)/det(RedCol_Nodes_Matrix));  %Port admitance (since it is a nodes matriz), meaning Y parameter (escalar value)
                    
                             if j ~= i
                                if (rem(i,2) == 1 && rem(j,2) == 0) || (rem(i,2) == 0 && rem(j,2) == 1)  
                                    Z_Parameter_Matrix(i,j) =  -1/Y_Parameter_Matrix(i,j);            
                                 else 
                                     Z_Parameter_Matrix(i,j) =  1/Y_Parameter_Matrix(i,j);
                                 end
                             else %j==i
                                Z_Parameter_Matrix(i,j) =  1/Y_Parameter_Matrix(i,j);        %Conversion de Parameter Y a Z diagonal principal.
                             end
                        end
                    
                    end
            
                    Z_Matrix(:,:,Step_num) = Z_Parameter_Matrix;
                end
            end
        end
        
        function Nodes_Matrix = Nodes_Matrix_Fun(app, Netlist_CellArray, Freq) 
            Size_NodesCellArray = size(Netlist_CellArray);

            %calculate the number of nodes for the matrix size
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            Nodes = unique(allNodesConn,'sorted'); 
            Nodes = Nodes(2:end); %Remove the reference node N0 (assumed it will always be named as that an therefore be at the top of the arrray)
            NodesNum = length(Nodes); 
            Nodes_Matrix = zeros(NodesNum,NodesNum); %Se crea una matriz de NxN donde N es la cantidad de puertos
            
            for i = 1 : NodesNum                                  %Iteracion en la cantidad de nodos para los renglones
                for j = 1 : NodesNum                              %Iteracion en la cantidad de nodos para las columas
                    for k = 1: Size_NodesCellArray(1,1)           %Iteracion para determinar los componentes en respectivo punto de la matriz nodal   
            
                        if strcmpi(Nodes(i),Nodes(j))             %Diagonal principal de la matriz de nodos
                            if strcmpi(Nodes(j),Netlist_CellArray(k,2)) || strcmpi(Nodes(j),Netlist_CellArray(k,3))    %siempre que el nodo en analisis este presente se sumara                
                                Bl=Netlist_CellArray{k,6};       % Para concentrados Bl=0 y no tiene efecto en Calc_Impedancia
                                opFreq=Netlist_CellArray{k,7};   % Para concentrados opFreq=0 y no tiene efecto en Calc_Impedancia
                                Nodes_Matrix(i,j) = Nodes_Matrix(i,j) + 1/(Calc_Impedance(app,Netlist_CellArray{k,4},Netlist_CellArray{k,5},Freq,Bl,opFreq));
                            end
                        else  %diagonales los elementos que interactuan con el nodo principal y no principal
                            if (strcmpi(Nodes(j),Netlist_CellArray(k,2)) || strcmpi(Nodes(j),Netlist_CellArray(k,3))) && (strcmpi(Nodes(i),Netlist_CellArray(k,2)) || strcmpi(Nodes(i),Netlist_CellArray(k,3)))
                                Bl=Netlist_CellArray{k,6};       % Para concentrados Bl=0 y no tiene efecto en Calc_Impedancia
                                opFreq=Netlist_CellArray{k,7};   % Para concentrados opFreq=0 y no tiene efecto en Calc_Impedancia
                                Nodes_Matrix(i,j) = Nodes_Matrix(i,j) - 1/(Calc_Impedance(app,Netlist_CellArray{k,4},Netlist_CellArray{k,5},Freq,Bl,opFreq));
                            end
                        end
            
                    end    
                end
            end
        end

        function Z = Calc_Impedance(app, Elem_Type, Value, Freq,Bl,opFreq)
            switch Elem_Type
                case 'R'
                    Z = Value;
                case 'L'
                    Z = 2*1i*pi*Value*Freq;
                case 'C'
                    Z = 1/(2*1i*pi*Freq*Value);
                case 'TL_Y1'
                    % Recordando que Y1=-Y21
                    Y=Y_Distribuidos(app,Freq,Value,Bl,opFreq,"TL");
                    Y1=-Y(2,1);
                    Z=1/Y1;
                case 'TL_Y2'
                    % Recordando que Y2=Y11+Y12
                    Y=Y_Distribuidos(app,Freq,Value,Bl,opFreq,"TL");
                    Y2=(Y(1,1)+Y(1,2));
                    Z=1/Y2;
                case 'TL_Y3'
                    % Recordando que Y3=Y22+Y12
                    Y=Y_Distribuidos(app,Freq,Value,Bl,opFreq,"TL");
                    Y3=(Y(2,2)+Y(1,2));
                    Z=1/Y3;
        
                case 'SSC'
                    Z=Y_Distribuidos(app,Freq,Value,Bl,opFreq,"SSC");
                    
                case 'SOC'
                    Z=Y_Distribuidos(app,Freq,Value,Bl,opFreq,"SOC");
                    
             end
        end
        
        function Y=Y_Distribuidos(app,f,Z,Bl,opFreq,tipo)
        
        
            % Constantes, obtener longitud [m] equivalente a la
            % longitud eléctrica en grados introducida por el usuario
            vp=3E8;
            oplambda=(vp/opFreq);
            opBeta=(2*pi)/(oplambda);
            l=Bl/opBeta;
    
    
            % Dependientes  de frecuencia
            lambda=(vp/f);
            Beta=(2*pi)/(lambda);
            
            % Segun el tipo de componente cambia A,B,C y D
            switch tipo
                case "TL"
                    A=cos(Beta*l);
                    B=1i*Z*sin(Beta*l);
                    C=1i*sin(Beta*l)/Z;
                    D=cos(Beta*l);
    
                    ABCD=[A B; C D];
                    Y=abcd2y(ABCD);
                case "SSC"
                    Bl=deg2rad(Bl);
                    Y=1i*Z*tan(Bl*(f/opFreq));
                case "SOC"
                    Bl=deg2rad(Bl);
                    Y=-1i*Z*cot(Bl*(f/opFreq));
                      
             end
                
        end

        function Freq_Value = Calc_Freq_Value(app, Freq_Field_Value, Freq_Unit)
            switch Freq_Unit
                case 'Hz'
                    Freq_Value = Freq_Field_Value; 
                case 'KHz'
                    Freq_Value = Freq_Field_Value*10^3; 
                case 'MHz' 
                    Freq_Value = Freq_Field_Value*10^6; 
                case 'GHz'
                    Freq_Value = Freq_Field_Value*10^9; 
                case 'THz'
                    Freq_Value = Freq_Field_Value*10^12; 
            end
        end
        
        function Retrieve_Values_For_Run(app) %Function called everytime run boton is pressed
            app.Start_Freq_Fld = app.StartFreqEditField.Value;
            app.End_Freq_Fld = app.EndFreqEditField.Value;
            app.Start_Freq = Calc_Freq_Value(app, app.Start_Freq_Fld, app.DropDownStartFreq.Value); 
            app.End_Freq = Calc_Freq_Value(app, app.End_Freq_Fld, app.DropDownEndFreq.Value); 
            app.Step = app.StepsEditField.Value;
            app.Zo = app.ZoEditField.Value;
            app.Port_Num = app.PortnumberEditField.Value;
            app.Frequencies = linspace(app.Start_Freq, app.End_Freq, app.Step); %linspace(x1,x2,n) generates n points. The spacing between the points is (x2-x1)/(n-1).
            app.Touchstone_Out_File_Name = app.OutputfileEditField.Value;
            app.Touchstone_Out_File_Format = app.FormatDropDown.Value;
            app.Touchstone_FreqUnit = app.FreqUnitDropDown.Value;
            app.RectPlotStyle = app.RectPlotStyleDropDown.Value;
        end


        function Y_Matrix = Y_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num)
            
            %calculate the number of nodes for the matrix size
            NetlistCASize = size(Netlist_CellArray);
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            AllNodes = unique(allNodesConn,'sorted'); 
            Step_num = 0; 
    
            if NetlistCASize(1,1) == 1 %Netlist has only one element, either a series or shunt element
                %For file netlist.net ground = 0, while for netlist.xlsx ground = N0
                if (ismember('N0', AllNodes) || ismember('0', AllNodes))
                    Y_Matrix = zeros(2,2);
                else
                    Y_Matrix = zeros(2,2, Step_num);
                    for F = Start_Freq:(End_Freq-Start_Freq)/(Step-1):End_Freq
                        Bl=Netlist_CellArray{1,6};       % Para concentrados Bl=0 y no tiene efecto en Calc_Impedancia
                        opFreq=Netlist_CellArray{1,7};   % Para concentrados opFreq=0 y no tiene efecto en Calc_Impedancia
                        Step_num = Step_num + 1;
                        Y_Matrix(1,1,Step_num) = 1/(Calc_Impedance(app,Netlist_CellArray{1,4},Netlist_CellArray{1,5},F,Bl,opFreq));
                        Y_Matrix(1,2,Step_num) = -1/(Calc_Impedance(app,Netlist_CellArray{1,4},Netlist_CellArray{1,5},F,Bl,opFreq));
                        Y_Matrix(2,1,Step_num) = -1/(Calc_Impedance(app,Netlist_CellArray{1,4},Netlist_CellArray{1,5},F,Bl,opFreq));
                        Y_Matrix(2,2,Step_num) = 1/(Calc_Impedance(app,Netlist_CellArray{1,4},Netlist_CellArray{1,5},F,Bl,opFreq));
                    end
                end
            else %Netlist has more than one element 
                Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                Y_Matrix = pageinv(Z_Matrix);
            end
        end
        
        function ABCD_Matrix = ABCD_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num)
            %calculate the number of nodes for the matrix size
            NetlistCASize = size(Netlist_CellArray);
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            AllNodes = unique(allNodesConn,'sorted'); 
    
             if Port_Num == 2
                if NetlistCASize(1,1) == 1 %Netlist has only one element, either a series or shunt element
                    if (ismember('N0', AllNodes) || ismember('0', AllNodes)) %For file netlist.net ground = 0, while for netlist.xlsx ground = N0
                        Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                        ABCD_Matrix = z2abcd(Z_Matrix);
                    else
                        Y_Matrix = Y_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                        ABCD_Matrix = y2abcd(Y_Matrix);
                    end
                else 
                    Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                    ABCD_Matrix = z2abcd(Z_Matrix); 
                end
             else
                 disp("ABCD parameters are only available for 2 port networks");
                 app.Warning_Msg = msgbox("ABCD parameters are only available for 2 port networks");
                 ABCD_Matrix = zeros(2,2);
             end
        end

        
        function Netlist_CellArray = Netlist2CellArray(app, netlist_file)

            % La función recibe el netlist de Spice, lo convierte a formato
            % de netlist de excel y saca el circuito equivalente 

            Touchstone_WR_File= fopen(netlist_file,'r','n','UTF-8');
            
            n=1;
            pat= lettersPattern;
            
            while(~feof(Touchstone_WR_File))
                nline = fgetl(Touchstone_WR_File);
                if(~contains(nline,'.end') && ~contains(nline,'*') && ~contains(nline,'.backanno')) 
                   splitted_nline = strsplit(nline);
                   NumVal= sip2num(splitted_nline{:,4});
                   
                   %Define the type of element from the component name.
                   %Note: Components must be named starting with the letter
                   %corresponding to the type of element their are, ej.
                   %Capacitor --> C1
                   elem_Type = splitted_nline{:,1};
                   elem_Type = cell2mat(extract(elem_Type,pat));

                   %For stubs and tranmision lines get Bl and frequency
                   if elem_Type=="SOC" || elem_Type=="SSC" || elem_Type=="TL" 
                       Bl= str2num(splitted_nline{:,5});
                       freq=str2num(splitted_nline{:,6});
                   else
                       Bl=0;
                       freq=0;
                   end
            
                   %Create custom cell array from netlist info that follows
                   %the excell format.
                   %Name, 1st Node, 2nd Node, type, Value
                    netlistCell(n,:) = [splitted_nline{:,1}, splitted_nline{:,2}, splitted_nline{:,3}, {elem_Type}, {NumVal}, Bl, freq];                    
                    n=n+1;
                end
            end

            % Inicialización de variable para guardar info
            X=1;
            X2=1;
        
            nueva_netlistCell= {};
            
                % Se recorre el netlist para encontrar distribuidos
            for i=1:size(netlistCell,1)
        
                    
                    nodoInicial=netlistCell{i,2};
                    nodoFinal=netlistCell{i,3};
                    type=cell2mat(netlistCell(i,4));
                    value=cell2mat(netlistCell(i,5));
                    Bl= cell2mat(netlistCell(i,6));
                    Bl=deg2rad(Bl);
                    opFreq=cell2mat(netlistCell(i,7));
            
                    if type=="TL" && nodoFinal~="0" 
                        % Guarda informacion de las lineas de transmision
                        Info(X,:)={nodoInicial,nodoFinal,value,Bl,opFreq,type};
                        X=X+1;
                    elseif type=="TL" && nodoFinal=="0" 
                        % Una linea de transmision a Tierra es un stub en corto circuito
                        newType= 'SSC'
                        nueva_netlistCell(X2,:)=[{newType},{nodoInicial},{nodoFinal},{newType},{value},netlistCell{i,6},{opFreq}];
                        X2=X2+1;
                    else
                        % Asigna directamente los elementos concentrados a la nueva
                        % lista
                        nueva_netlistCell(X2,:)=netlistCell(i,:);
                        X2=X2+1;
                    end
            
            end
            
            % Con la información obtenida, crea el nuevo netlist
            % Variable Info no existe cuando una netlist es completamente de concentrados
            if exist("Info","var")
                for j=1:size(Info,1)
                
                % Agregar circuito equivalente
                % Y1=Y21, Y2=Y11+Y12 y Y3=Y22+Y12
                tipo=Info{j,6};
                
                NombreY1=[tipo '_Y1'];
                NombreY2=[tipo '_Y2'];
                NombreY3=[tipo '_Y3'];
        
                Y1=[{NombreY1},{Info{j,1}},{Info{j,2}},{NombreY1},{Info{j,3}},{Info{j,4}},{Info{j,5}}];
                Y2=[{NombreY2},{Info{j,1}},{'0'},{NombreY2},{Info{j,3}},{Info{j,4}},{Info{j,5}}];
                Y3=[{NombreY3},{Info{j,2}},{'0'},{NombreY3},{Info{j,3}},{Info{j,4}},{Info{j,5}}];
                
                % Se guardan las nuevas impedancias al netlist
                last_column=size(nueva_netlistCell,1)+1;
                nueva_netlistCell(last_column:last_column+2,:)=[Y1;Y2;Y3];
        
                end
            end
        
            Netlist_CellArray=nueva_netlistCell;


        end   
        
        function Plot_Rect_Charts(app, S_parameter_obj) %test_parameters is a S parameter object, %Only plots S11 to S22 params (as a two port network)
            app.AllRectAxes = [app.UIAxesS11, app.UIAxesS12; app.UIAxesS21, app.UIAxesS22];
            if strcmp(app.RectPlotStyle,'Magnitude')
                for i = 1 : 2
                    for j = 1: 2
                        axis = app.AllRectAxes(i,j);
                        rfplot(axis,S_parameter_obj,i,j);
                    end
                end
            elseif strcmp(app.RectPlotStyle,'Phase')
                for i = 1 : 2
                    for j = 1: 2
                        axis = app.AllRectAxes(i,j);
                        rfplot(axis,S_parameter_obj,i,j,'angle');
                    end
                end
            elseif strcmp(app.RectPlotStyle,'Real')
                for i = 1 : 2
                    for j = 1: 2
                        axis = app.AllRectAxes(i,j);
                        rfplot(axis,S_parameter_obj,i,j,'real');
                    end
                end
            else %elseif app.RectPlotStyle == 'Imaginary'
                for i = 1 : 2
                    for j = 1: 2
                        axis = app.AllRectAxes(i,j);
                        rfplot(axis,S_parameter_obj,i,j,'imag');
                    end
                end
            end
        end
        
        function Plot_Polar_Charts(app, S_parameter_obj) %Only plots S11 to S22 params (as a two port network)
            app.AllPolarAxes = [app.PolAxesS11, app.PolAxesS12; app.PolAxesS21, app.PolAxesS22];
            for i = 1 : 2
                for j = 1: 2
                    axis = app.AllPolarAxes(i,j);
                    s_parameter = rfparam(S_parameter_obj,i,j);
                    polarplot(axis,s_parameter); %polarplot(2+3i,'*')
                    axis.Title.String = strcat('S',int2str(i),int2str(j));
                    %axis.LineWidth = 1.5;
                end
            end
        end

        
        function Plot_Smith_charts(app, S_parameter_obj)
            app.AllSmithAxes = [app.S11SmithChartPanel, app.S12SmithChartPanel; app.S21SmithChartPanel, app.S22SmithChartPanel];
            for i = 1 : 2
                for j = 1: 2
                    axis = app.AllSmithAxes(i,j);
                    %s_parameter = rfparam(S_parameter_obj,i,j);
                    %s = smithplot() %returns a Smith chart object handle
                    %so you can customize the plot and add measurements. 
                    %The above in case we would like the axis to be visible
                    %before plotting, at startupFcn
                    smithplot(S_parameter_obj,i,j,Parent=axis); %smithplot('Parent', uipanel) smithplot(s,1,1, 'GridType','ZY');
                end
            end
        end
        
        function S_Matrix = S_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num, Z0)
            %calculate the number of nodes for the matrix size
            NetlistCASize = size(Netlist_CellArray);
            nodesC2 = Netlist_CellArray(:,2); %elements start nodes
            nodesC3 = Netlist_CellArray(:,3); %elements end nodes
            allNodesConn = [nodesC2; nodesC3];
            AllNodes = unique(allNodesConn,'sorted'); 
    
            if NetlistCASize(1,1) == 1
                if (ismember('N0', AllNodes) || ismember('0', AllNodes)) %For file netlist.net ground = 0, while for netlist.xlsx ground = N0
                    Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                    S_Matrix = z2s(Z_Matrix,Z0);
                else
                    Y_Matrix = Y_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                    S_Matrix = y2s(Y_Matrix,Z0);
                end
            else 
                Z_Matrix = Z_parameters(app, Netlist_CellArray, Start_Freq, End_Freq, Step, Port_Num);
                S_Matrix = z2s(Z_Matrix,Z0);
            end
        end
        
        function [S_Matrix_CTC_Network, frequencies, Z0, port_num] = Circuit_Touchstone_Connection(app, First_Netlist_CellArray, Touchstone_S_Obj, Second_Netlist_CellArray)
            
            %obj info created from touchstone
            port_num = 2;
            frequencies = Touchstone_S_Obj.Frequencies;
            step = length(frequencies);
            start_freq = frequencies(1,1);
            end_freq = frequencies(step,1);
            Z0 = Touchstone_S_Obj.Impedance;
            S_Matrix_Touchstone = Touchstone_S_Obj.Parameters; %UPDATE: MIGTH CHANGE ACORDING READING FUNCTION

            if ~strcmp(First_Netlist_CellArray,'None')
                ABCD_Matrix_1st_Netlist = ABCD_parameters(app, First_Netlist_CellArray, start_freq, end_freq, step, port_num);
            else 
                ABCD_Matrix_1st_Netlist =1;
            end

            if ~strcmp(Second_Netlist_CellArray,'None')
                ABCD_Matrix_2st_Netlist = ABCD_parameters(app, Second_Netlist_CellArray, start_freq, end_freq, step, port_num);
            else 
                ABCD_Matrix_2st_Netlist = 1;
            end 

            ABCD_Matrix_Touchstone = s2abcd(S_Matrix_Touchstone,Z0);
            ABCD_1stNet_Tch = pagemtimes(ABCD_Matrix_1st_Netlist,ABCD_Matrix_Touchstone);
            ABCD_1stNet_Tch_2ndNet = pagemtimes(ABCD_1stNet_Tch,ABCD_Matrix_2st_Netlist);
            S_Matrix_CTC_Network = abcd2s(ABCD_1stNet_Tch_2ndNet,Z0);
        end
        
        function Reset_Values_Fun(app)
            app.StartFreqEditField.Value = 1;
            app.EndFreqEditField.Value = 1;
            app.DropDownStartFreq.Value = 'Hz';
            app.DropDownEndFreq.Value = 'Hz';
            app.StepsEditField.Value = 1;
            app.ZoEditField.Value = 50;
            app.PortnumberEditField.Value = 2;
            app.OutputfileEditField.Value = 'parameters';
            app.InputfileEditField.Value = '';
            app.FormatDropDown.Value = 'RI';
            app.FreqUnitDropDown.Value = 'Hz';
            app.ListBoxNetlistFiles.Items = {};  
            app.ListBoxNetlistFiles.ItemsData = {}; 
            app.ListBoxTouchstoneFiles.Items = {};
            app.ListBoxTouchstoneFiles.ItemsData = {};
            app.Netlist1ListBox.Items = {'None'};
            app.Netlist1ListBox.ItemsData = {'None'};
            app.Netlist2ListBox.Items = {'None'};
            app.Netlist2ListBox.ItemsData = {'None'};
            app.TouchstoneListBox.Items = {};
            app.TouchstoneListBox.ItemsData = {};            
            app.Parameters_Ready = false;
            app.Run_Valid = false;
            %clear netlist table
            varTypes = ["string","string","string", "string","string"];
            varNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value'};
            app.Netlist_table = table('Size',[1 5],'VariableTypes',varTypes,'VariableNames',varNames); %[row col]
            app.Netlist_table{:,:} = zeros(1,5);
            app.UITable.Data = app.Netlist_table;
        end
        
        function Write_Touchstone(app, SMatrix, Frequencies, Touchstone_Out_File, FrequencyUnit, Z0, Format)
            %Note: Frequencies must be an array of a single column (n,1) as defined for sparameters objects, otherwise line 555 will fail. 
            sz_SMatrix = size(SMatrix);
            %Touchstone_Out_File = append(Touchstone_Out_File,'.s',num2str(sz_SMatrix(1,1)),'p');
            Touchstone_WR_File = fopen(Touchstone_Out_File,'wt+');
            
            switch FrequencyUnit
                case 'Hz'
                    div_factor = 1;
                case 'KHz'
                    div_factor = 1e3;
                case 'MHz'
                    div_factor = 1e6;
                case 'GHz'
                    div_factor = 1e9;
            end
            
            switch Format
                case 'RI'
                    fprintf(Touchstone_WR_File,'%s %1s %1s %1s %1s %1s\n','#',FrequencyUnit,'S',Format,'R',num2str(Z0));
                    fprintf(Touchstone_WR_File,'\n');
                    a = real(SMatrix);
                    b = imag(SMatrix);
            
                case 'MA'
                    fprintf(Touchstone_WR_File,'%s %1s %1s %1s %1s %1s\n','#',FrequencyUnit,'S',Format,'R',num2str(Z0));
                    fprintf(Touchstone_WR_File,'\n');
                    a = abs(SMatrix);
                    b = rad2deg(angle(SMatrix));
            
                case 'DB'
                    fprintf(Touchstone_WR_File,'%s %1s %1s %1s %1s %1s\n','#',FrequencyUnit,'S',Format,'R',num2str(Z0));
                    fprintf(Touchstone_WR_File,'\n');
                    a = mag2db(abs(SMatrix));        
                    b = rad2deg(angle(SMatrix));
            end
            
            for i = 1 : sz_SMatrix(1,3)             %Itera en la cantidad de frencuencias
                fprintf(Touchstone_WR_File,'%1.15e ',(Frequencies(i,1)/(div_factor)));
                for j = 1 : sz_SMatrix(1,1)         %Itera en los renglones de la matriz
                    for k = 1 : sz_SMatrix(1,2)     %Itera en las columnas de la matriz
        
                        if ((k == sz_SMatrix(1,2)) && (j ~= sz_SMatrix(1,1)))               
                            fprintf(Touchstone_WR_File,' % 1.16e % 1.16e ', a(j,k,i), b(j,k,i));
                        elseif ((k == sz_SMatrix(1,2)) && (j == sz_SMatrix(1,1)))
                            fprintf(Touchstone_WR_File,' % 1.16e % 1.16e', a(j,k,i), b(j,k,i));
                        else
                            fprintf(Touchstone_WR_File,'% 1.16e % 1.16e', a(j,k,i), b(j,k,i));
                        end
        
                    end
                end
                fprintf(Touchstone_WR_File,'\n');
            end
            
            fclose(Touchstone_WR_File);
        end
        

        function [SMatrix, Frequencies, Z0] = Read_Touchstones(app, Touchstone_Input_File)
            fileID = fopen(Touchstone_Input_File,'r');
            
            S_parameters_data = textscan(fileID,'%f %f %f %f %f %f %f %f %f','CommentStyle','!','HeaderLines',2);
            
            frewind(fileID);
            while true
               Datos_Formato = fgets(fileID);
               Datos_Formato = split(Datos_Formato,' ');
                if strcmpi(Datos_Formato(1,1),'#')
                    break
                end
            end
            
            Formato_datos = cell2mat(Datos_Formato(4,1));
            Formato_datos = lower(Formato_datos);
            Unidad_Frecuencia = cell2mat(Datos_Formato(2,1));
            Unidad_Frecuencia = lower(Unidad_Frecuencia);
            %Puertos = 2;
            Z0 = cell2mat(Datos_Formato(6,1));
            Z0 = str2double(Z0);
            
            switch Unidad_Frecuencia
            
                case 'hz'
                    multiplicador = 1;
                case 'khz'
                    multiplicador = 1e3;
                case 'mhz'
                    multiplicador = 1e6;       
                case'ghz'
                    multiplicador = 1e9;
            
            end
            
            Frequencies = (S_parameters_data{1,1})*multiplicador;
            
            D_1 = S_parameters_data{1,2};
            D_2 = S_parameters_data{1,3};
            D_3 = S_parameters_data{1,4};
            D_4 = S_parameters_data{1,5};
            D_5 = S_parameters_data{1,6};
            D_6 = S_parameters_data{1,7};
            D_7 = S_parameters_data{1,8};
            D_8 = S_parameters_data{1,9};
            sz_2 = size(D_1);
            SMatrix = zeros(2,2,sz_2(1,1));
            
            switch Formato_datos
                case 'ma'        
                    for i = 1 : sz_2(1,1)
                        SMatrix(1,1,i) = D_1(i,1)*cos(deg2rad(D_2(i,1))) + D_1(i,1)*sin(deg2rad(D_2(i,1)))*1i;
                        SMatrix(1,2,i) = D_3(i,1)*cos(deg2rad(D_4(i,1))) + D_3(i,1)*sin(deg2rad(D_4(i,1)))*1i;
                        SMatrix(2,1,i) = D_5(i,1)*cos(deg2rad(D_6(i,1))) + D_5(i,1)*sin(deg2rad(D_6(i,1)))*1i;
                        SMatrix(2,2,i) = D_7(i,1)*cos(deg2rad(D_8(i,1))) + D_7(i,1)*sin(deg2rad(D_8(i,1)))*1i;
                    end
            
                case 'ri'     
                     for i = 1 : sz_2(1,1)
                        SMatrix(1,1,i) = D_1(i,1) + D_2(i,1)*1i;
                        SMatrix(1,2,i) = D_3(i,1) + D_4(i,1)*1i;
                        SMatrix(2,1,i) = D_5(i,1) + D_6(i,1)*1i;
                        SMatrix(2,2,i) = D_7(i,1) + D_8(i,1)*1i;
                     end
            
                case 'db'
                    for i = 1 : sz_2(1,1)
                        SMatrix(1,1,i) = db2mag(D_1(i,1))*cos(deg2rad(D_2(i,1))) + db2mag(D_1(i,1))*sin(deg2rad(D_2(i,1)))*1i;
                        SMatrix(1,2,i) = db2mag(D_3(i,1))*cos(deg2rad(D_4(i,1))) + db2mag(D_3(i,1))*sin(deg2rad(D_4(i,1)))*1i;
                        SMatrix(2,1,i) = db2mag(D_5(i,1))*cos(deg2rad(D_6(i,1))) + db2mag(D_5(i,1))*sin(deg2rad(D_6(i,1)))*1i;
                        SMatrix(2,2,i) = db2mag(D_7(i,1))*cos(deg2rad(D_8(i,1))) + db2mag(D_7(i,1))*sin(deg2rad(D_8(i,1)))*1i;
                    end
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PolAxesS11 = polaraxes('parent',app.GridLayout7);
            app.PolAxesS11.Title.String = 'S11';
            app.PolAxesS12 = polaraxes('parent',app.GridLayout7);
            app.PolAxesS12.Title.String = 'S12';
            app.PolAxesS21 = polaraxes('parent',app.GridLayout7);
            app.PolAxesS21.Title.String = 'S21';
            app.PolAxesS22 = polaraxes('parent',app.GridLayout7);
            app.PolAxesS22.Title.String = 'S22';
            %app.AllPolarAxes = [app.PolAxesS11, app.PolAxesS12; app.PolAxesS21, app.PolAxesS22];
        end

        % Menu selected function: xlsxfileMenu
        function xlsxfileMenuSelected(app, event)
            [app.xslx_fileName,app.xslx_filePath] = uigetfile('*.xlsx');
            if isequal(app.xslx_fileName,0)
                disp('User selected Cancel');
                app.Run_Valid = false;
            else
                disp(['User selected ', fullfile(app.xslx_filePath,app.xslx_fileName)]);
                app.xslx_file = fullfile(app.xslx_filePath,app.xslx_fileName);
                app.Netlist_table = readtable(app.xslx_file); 
                app.Netlist_table.Properties.VariableNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value'}; % names of columns
                app.UITable.Data = app.Netlist_table;
                app.Netlist_table.Properties.VariableDescriptions{1} = 'Name'; 
                app.Netlist_table.Properties.VariableDescriptions{2} = '1st Node';
                app.Netlist_table.Properties.VariableDescriptions{3} = '2nd Node';
                app.Netlist_table.Properties.VariableDescriptions{4} = 'Type';
                app.Netlist_table.Properties.VariableDescriptions{5} = 'Value';
                app.UITable.ColumnName = app.Netlist_table.Properties.VariableDescriptions;
                app.Netlist_CellArray = table2cell(app.Netlist_table); 
                app.ListBoxNetlistFiles.Items = [app.ListBoxNetlistFiles.Items, {app.xslx_fileName}]; %horizontal concatenation
                app.ListBoxNetlistFiles.ItemsData = [app.ListBoxNetlistFiles.ItemsData, {app.Netlist_CellArray}];
                app.ListBoxNetlistFiles.Value = app.Netlist_CellArray;

                %1st netlist to CTC simulation
                app.Netlist1ListBox.Items = [app.Netlist1ListBox.Items, {app.xslx_fileName}];
                app.Netlist1ListBox.ItemsData = [app.Netlist1ListBox.ItemsData, {app.Netlist_CellArray}];
                %app.CTC_Netlist1 = app.Netlist1ListBox.Value;

                %2st netlist to CTC simulation
                app.Netlist2ListBox.Items = [app.Netlist2ListBox.Items, {app.xslx_fileName}];
                app.Netlist2ListBox.ItemsData = [app.Netlist2ListBox.ItemsData, {app.Netlist_CellArray}];
                %app.CTC_Netlist2 = app.Netlist2ListBox.Value;

                app.Run_Valid = true;
            end

            %notify(app,"xlsxNetlistChanged");
            %app.FileName.Value = [fileName,filePath];
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Retrieve_Values_For_Run(app);
            if app.Run_Valid
                app.Parameters_Ready = false;
                progDlg = uiprogressdlg(app.UIFigure,'Title','Simulator info', 'Message','Start of simulation');
                pause(.5)

                % Perform calculations
                progDlg.Value = .33; 
                progDlg.Message = 'Loading data';
                pause(.5)

                % Perform calculations
                progDlg.Value = .67;
                progDlg.Message = 'Calculating circuit parameters';
                pause(.5)
     
                    app.ZMatrix = Z_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num); 
                    app.YMatrix = Y_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num); 
                    app.ABCD_Matrix = ABCD_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num);
                    app.SMatrix = S_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num, app.Zo);
    
                    app.Parameters_Ready = true; %For touchstone storage
                    
                    app.S_Parameters_Obj = sparameters(app.SMatrix,app.Frequencies,app.Zo);
                    Plot_Rect_Charts(app, app.S_Parameters_Obj);
                    Plot_Polar_Charts(app, app.S_Parameters_Obj);
                    Plot_Smith_charts(app, app.S_Parameters_Obj);

                % try
                %     app.ZMatrix = Z_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num); 
                %     app.YMatrix = Y_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num); 
                %     app.ABCD_Matrix = ABCD_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num);
                %     app.SMatrix = S_parameters(app, app.Netlist_CellArray, app.Start_Freq, app.End_Freq, app.Step, app.Port_Num, app.Zo);
                % 
                %     app.Parameters_Ready = true; %For touchstone storage
                % 
                %     app.S_Parameters_Obj = sparameters(app.SMatrix,app.Frequencies,app.Zo);
                %     Plot_Rect_Charts(app, app.S_Parameters_Obj);
                %     Plot_Polar_Charts(app, app.S_Parameters_Obj);
                %     Plot_Smith_charts(app, app.S_Parameters_Obj);
                % 
                % catch ME % ME is of the class MException (in-built Matlab)
                %     str = sprintf(['Parameters calculation & ploting problem \n' 'Matlab says: \n' ME.message]);
                %     app.Warning_Msg = msgbox(str,'Error: Simulation fail');
                %     disp(str)
                %     app.Parameters_Ready = false;
                % end
                

                % Finish calculations
                progDlg.Value = 1;
                progDlg.Message = 'Simulation complete';
                pause(.8)
            
                % Close dialog box
                close(progDlg) 
            else 
                app.Warning_Msg = msgbox("Please load a netlist before running a simulation","Warning: Missing netlist");
                app.Parameters_Ready = false;
            end
        end

        % Value changed function: StartFreqEditField
        function StartFreqEditFieldValueChanged(app, event)
            if app.StartFreqEditField.Value == 0
                app.StartFreqEditField.Value = 1;
                app.Start_Freq_Fld = app.StartFreqEditField.Value;
                app.Start_Freq = Calc_Freq_Value(app, app.Start_Freq_Fld, app.DropDownStartFreq.Value); 
                app.Warning_Msg = msgbox("Star frequency must be different than 0","Warning: Wrong star frequency value");
            else
                app.Start_Freq_Fld = app.StartFreqEditField.Value;
                app.Start_Freq = Calc_Freq_Value(app, app.Start_Freq_Fld, app.DropDownStartFreq.Value); 
            end
        end

        % Value changed function: EndFreqEditField
        function EndFreqEditFieldValueChanged(app, event)
            if app.EndFreqEditField.Value == 0
                app.EndFreqEditField.Value = 1;
                app.End_Freq_Fld = app.EndFreqEditField.Value;
                app.Warning_Msg = msgbox("End frequency must be different than 0","Warning: Wrong end frequency value");
            else
                app.End_Freq_Fld = app.EndFreqEditField.Value; 
                app.End_Freq = Calc_Freq_Value(app, app.End_Freq_Fld, app.DropDownEndFreq.Value);  
            end
        end

        % Value changed function: DropDownStartFreq
        function DropDownStartFreqValueChanged(app, event)
            app.Start_Freq = Calc_Freq_Value(app, app.StartFreqEditField.Value, app.DropDownStartFreq.Value);
            %app.Start_Freq = Calc_Freq_Value(app, app.Start_Freq_Fld, app.DropDownStartFreq.Value);
        end

        % Value changed function: DropDownEndFreq
        function DropDownEndFreqValueChanged(app, event)
            app.End_Freq = Calc_Freq_Value(app, app.End_Freq_Fld, app.DropDownEndFreq.Value);
            %app.End_Freq = Calc_Freq_Value(app, app.EndFreqEditField.Value, app.DropDownEndFreq.Value);
        end

        % Value changed function: StepsEditField
        function StepsEditFieldValueChanged(app, event)
            app.Step = app.StepsEditField.Value;
        end

        % Value changed function: PortnumberEditField
        function PortnumberEditFieldValueChanged(app, event)
            app.Port_Num = floor(app.PortnumberEditField.Value);
            app.PortnumberEditField.Value = app.Port_Num;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if app.Parameters_Ready
                app.Touchstone_Out_File_Name = app.OutputfileEditField.Value;
                app.Touchstone_Out_File_Format = app.FormatDropDown.Value;
                app.Touchstone_FreqUnit = app.FreqUnitDropDown.Value;

                if contains(app.Touchstone_Out_File_Name,'.s')
                    app.Touchstone_Out_File_Name = split(app.Touchstone_Out_File_Name,'.');
                    app.Touchstone_Out_File_Name = app.Touchstone_Out_File_Name{1};
                end
                
                sz_SMatrix = size(app.SMatrix);
                %app.Touchstone_Out_File_Name = append(app.Touchstone_Out_File_Name,'.s',num2str(sz_SMatrix(1,1)),'p');
                File_Filter = strcat('*.s',num2str(sz_SMatrix(1,1)),'p');
                [app.Touchstone_Out_File_Name, app.Touchstone_Out_File_Path] = uiputfile(File_Filter,'Select the directory to save the file',app.Touchstone_Out_File_Name);

                if isequal(app.Touchstone_Out_File_Name,0) || isequal(app.Touchstone_Out_File_Path,0)
                   disp('User clicked Cancel.')
                   app.OutputfileEditField.Value = 'parameters'; %default value
                   app.Warning_Msg = msgbox("Save touchstone operation was cancelled","Warning");
                else
                   disp(['User selected ',fullfile(app.Touchstone_Out_File_Path,app.Touchstone_Out_File_Name),' and then clicked Save.'])
                   app.OutputfileEditField.Value = app.Touchstone_Out_File_Name; %UPDATE: name includes format .s*p, yet if user changes the format while on the save window, it migt have an erratic behavior, migth need to implement the above to correct the file name.
                   app.Touchstone_Out_File = fullfile(app.Touchstone_Out_File_Path, app.Touchstone_Out_File_Name);
                   %rfwrite(app.SMatrix,app.Frequencies,app.Touchstone_Out_File, 'FrequencyUnit', app.Touchstone_FreqUnit, 'ReferenceResistance', app.Zo, 'Format', app.Touchstone_Out_File_Format, 'ForceOverwrite', true);  
                   Write_Touchstone(app, app.S_Parameters_Obj.Parameters, app.S_Parameters_Obj.Frequencies, app.Touchstone_Out_File, app.Touchstone_FreqUnit, app.S_Parameters_Obj.Impedance, app.Touchstone_Out_File_Format)
                   %Write_Touchstone(app, app.SMatrix, app.Frequencies, app.Touchstone_Out_File, app.Touchstone_FreqUnit, app.Zo, app.Touchstone_Out_File_Format)
                end
            else 
                app.Warning_Msg = msgbox("Please run a simulation before exporting file","Warning: No simulation was ran");
            end
        end

        % Value changed function: OutputfileEditField
        function OutputfileEditFieldValueChanged(app, event)
            app.Touchstone_Out_File_Name = app.OutputfileEditField.Value;
        end

        % Value changed function: ZoEditField
        function ZoEditFieldValueChanged(app, event)
            app.Zo = app.ZoEditField.Value;
        end

        % Value changed function: FormatDropDown
        function FormatDropDownValueChanged(app, event)
            app.Touchstone_Out_File_Format = app.FormatDropDown.Value;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            %Method to read a touchstone file
            [app.Touchstone_Input_File_Name,app.Touchstone_Input_File_Path] = uigetfile('*.*');
            if isequal(app.Touchstone_Input_File_Name,0) 
                disp('User selected Cancel');
            else
                if contains(app.Touchstone_Input_File_Name,'.s')
                    app.Touchstone_Input_File = fullfile(app.Touchstone_Input_File_Path,app.Touchstone_Input_File_Name);
                    disp(['User selected ', app.Touchstone_Input_File]);
                    %app.SMatrix_Touchstone = sparameters(app.Touchstone_Input_File); %SMatrix_Touchstone is a S-parameter object UPDATE WITH CUSTOM FUN!!!
                    [app.SMatrix, app.Frequencies, app.Zo] = Read_Touchstones(app, app.Touchstone_Input_File);
                    app.SMatrix_Touchstone = sparameters(app.SMatrix,app.Frequencies,app.Zo);
                    app.InputfileEditField.Value = app.Touchstone_Input_File_Name;
                    app.ListBoxTouchstoneFiles.Items = [app.ListBoxTouchstoneFiles.Items, {app.Touchstone_Input_File_Name}]; %horizontal concatenation
                    app.ListBoxTouchstoneFiles.ItemsData = [app.ListBoxTouchstoneFiles.ItemsData, {app.SMatrix_Touchstone}];
                    %CTC simulation
                    if contains(app.Touchstone_Input_File_Name,'.s2p') %As this function is limited for 2 ports blocks
                        app.TouchstoneListBox.Items = [app.TouchstoneListBox.Items, {app.Touchstone_Input_File_Name}]; 
                        app.TouchstoneListBox.ItemsData = [app.TouchstoneListBox.ItemsData, {app.SMatrix_Touchstone}];%UPDATE ACCORDING THE ABOVE
                    end
                else
                    disp('Invalid file, must select a .s*p file');
                    app.Warning_Msg = msgbox("Invalid file, must select a .s*p file","Warning: Invalid input file");
                    app.Touchstone_Input_File = '';
                    app.InputfileEditField.items = app.Touchstone_Input_File;
                end
            end
        end

        % Menu selected function: netfileMenu
        function netfileMenuSelected(app, event)
            [app.net_fileName,app.net_filePath] = uigetfile('*.net'); %Files .cir could also be read
            if isequal(app.net_fileName,0)
                disp('User selected Cancel');
                app.Run_Valid = false;
            else
                disp(['User selected ', fullfile(app.net_filePath,app.net_fileName)]);
                app.net_file = fullfile(app.net_filePath,app.net_fileName);
                app.Netlist_CellArray = Netlist2CellArray(app, app.net_file); 
                app.Netlist_table = cell2table(app.Netlist_CellArray);
                app.Netlist_table.Properties.VariableNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value','Bl', 'Operating Frequency'};
                app.UITable.Data = app.Netlist_table;
                app.ListBoxNetlistFiles.Items = [app.ListBoxNetlistFiles.Items, {app.net_fileName}]; %horizontal concatenation
                app.ListBoxNetlistFiles.ItemsData = [app.ListBoxNetlistFiles.ItemsData, {app.Netlist_CellArray}];
                app.ListBoxNetlistFiles.Value = app.Netlist_CellArray;

                %1st netlist to CTC simulation
                app.Netlist1ListBox.Items = [app.Netlist1ListBox.Items, {app.net_fileName}];
                app.Netlist1ListBox.ItemsData = [app.Netlist1ListBox.ItemsData, {app.Netlist_CellArray}];
                %app.CTC_Netlist1 = app.Netlist1ListBox.Value;
                
                %2st netlist to CTC simulation
                app.Netlist2ListBox.Items = [app.Netlist2ListBox.Items, {app.net_fileName}];
                app.Netlist2ListBox.ItemsData = [app.Netlist2ListBox.ItemsData, {app.Netlist_CellArray}];
                %app.CTC_Netlist2 = app.Netlist2ListBox.Value;

                app.Run_Valid = true;
            end  
        end

        % Value changed function: PlotSelectorDropDown
        function PlotSelectorDropDownValueChanged(app, event)
            %Callback to open any selected plot in a different window
            value = app.PlotSelectorDropDown.Value;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            app.Parameters_Ready = false;
            app.CTC_Netlist1 = app.Netlist1ListBox.Value;
            app.CTC_Netlist2  = app.Netlist2ListBox.Value;
            app.CTC_Touchstone = app.TouchstoneListBox.Value;
     
            if isempty(app.CTC_Touchstone)
                app.Warning_Msg = msgbox("Please select a Touchstone file from CTC tab","Warning: No simulation was ran");
            else
                progDlg = uiprogressdlg(app.UIFigure,'Title','Simulator info', 'Message','Start of simulation');
                pause(.5)
    
                % Perform calculations
                progDlg.Value = .33; 
                progDlg.Message = 'Loading data';
                pause(.5)
    
                % Perform calculations
                progDlg.Value = .67;
                progDlg.Message = 'Calculating circuit parameters';
                pause(.5)
                [app.SMatrix, app.Frequencies, app.Zo, app.Port_Num] = Circuit_Touchstone_Connection(app, app.CTC_Netlist1, app.CTC_Touchstone, app.CTC_Netlist2);
                
                %Updating fields according to CTC simulation
                app.StartFreqEditField.Value = app.Frequencies(1,1);
                app.DropDownStartFreq.Value = 'Hz'; 
                app.StepsEditField.Value = length(app.Frequencies);
                app.EndFreqEditField.Value = app.Frequencies(app.StepsEditField.Value,1);
                app.DropDownEndFreq.Value = 'Hz';
                app.PortnumberEditField.Value = app.Port_Num;
                app.ZoEditField.Value = app.Zo;
    
                %Ploting results
                app.S_Parameters_Obj = sparameters(app.SMatrix,app.Frequencies,app.Zo);
                Plot_Rect_Charts(app, app.S_Parameters_Obj);
                Plot_Polar_Charts(app, app.S_Parameters_Obj);
                Plot_Smith_charts(app, app.S_Parameters_Obj);

                app.Parameters_Ready = true; %For touchstone file storage

                % Finish calculations
                progDlg.Value = 1;
                progDlg.Message = 'Simulation complete';
                pause(.5)
            
                % Close dialog box
                close(progDlg) 
            end
        end

        % Value changed function: Netlist1ListBox
        function Netlist1ListBoxValueChanged(app, event)
            app.CTC_Netlist1 = app.Netlist1ListBox.Value;
        end

        % Value changed function: Netlist2ListBox
        function Netlist2ListBoxValueChanged(app, event)
            app.CTC_Netlist2  = app.Netlist2ListBox.Value;
        end

        % Value changed function: ListBoxNetlistFiles
        function ListBoxNetlistFilesValueChanged(app, event)
            app.Netlist_CellArray = app.ListBoxNetlistFiles.Value;
            app.Netlist_table = cell2table(app.Netlist_CellArray);
            app.Netlist_table.Properties.VariableNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value'};
            app.UITable.Data = app.Netlist_table;
        end

        % Value changed function: TouchstoneListBox
        function TouchstoneListBoxValueChanged(app, event)
            app.CTC_Touchstone = app.TouchstoneListBox.Value;
        end

        % Value changed function: ListBoxTouchstoneFiles
        function ListBoxTouchstoneFilesValueChanged(app, event)
            app.Sel_Touchstone = app.ListBoxTouchstoneFiles.Value; %Only displays the loaded touchtone files
        end

        % Value changed function: FreqUnitDropDown
        function FreqUnitDropDownValueChanged(app, event)
            app.Touchstone_FreqUnit = app.FreqUnitDropDown.Value;
        end

        % Menu selected function: ClearTourchstonefilesMenu
        function ClearTourchstonefilesMenuSelected(app, event)
            app.ListBoxTouchstoneFiles.Items = {};
            app.ListBoxTouchstoneFiles.ItemsData = {};
            app.TouchstoneListBox.Items = {};
            app.TouchstoneListBox.ItemsData = {};
            app.InputfileEditField.Value = '';
            app.OutputfileEditField.Value = 'parameters';
        end

        % Menu selected function: ClearNetlistfilesMenu
        function ClearNetlistfilesMenuSelected(app, event)
            app.ListBoxNetlistFiles.Items = {};  
            app.ListBoxNetlistFiles.ItemsData = {}; 
            app.Netlist1ListBox.Items = {'None'};
            app.Netlist1ListBox.ItemsData = {'None'};
            app.Netlist2ListBox.Items = {'None'};
            app.Netlist2ListBox.ItemsData = {'None'};
            %clear netlist table
            varTypes = ["string","string","string", "string","string"];
            varNames = {'Name', '1st Node', '2nd Node', 'Type', 'Value'};
            app.Netlist_table = table('Size',[1 5],'VariableTypes',varTypes,'VariableNames',varNames); %[row col]
            app.Netlist_table{:,:} = zeros(1,5);
            app.UITable.Data = app.Netlist_table;
        end

        % Menu selected function: ClearAllMenu
        function ClearAllMenuSelected(app, event)
            Reset_Values_Fun(app);
        end

        % Value changed function: RectPlotStyleDropDown
        function RectPlotStyleDropDownValueChanged(app, event)
            app.RectPlotStyle = app.RectPlotStyleDropDown.Value;

            if app.Parameters_Ready %app.S_Parameters_Obj property stores the S param results from sim.
                %app.S_Parameters_Obj = sparameters(app.SMatrix,app.Frequencies,app.Zo);
                Plot_Rect_Charts(app, app.S_Parameters_Obj); 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NetlistMenu = uimenu(app.UIFigure);
            app.NetlistMenu.Text = 'Netlist';
            app.OpenMenu = uimenu(app.NetlistMenu);
            app.OpenMenu.Text = 'Open';
            app.netfileMenu = uimenu(app.OpenMenu);
            app.netfileMenu.MenuSelectedFcn = createCallbackFcn(app, @netfileMenuSelected, true);
            app.netfileMenu.Text = '.net file';
            app.xlsxfileMenu = uimenu(app.OpenMenu);
            app.xlsxfileMenu.MenuSelectedFcn = createCallbackFcn(app, @xlsxfileMenuSelected, true);
            app.xlsxfileMenu.Text = '.xlsx file';
            app.ClearMenu = uimenu(app.UIFigure);
            app.ClearMenu.Text = 'Clear';
            app.ClearAllMenu = uimenu(app.ClearMenu);
            app.ClearAllMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearAllMenuSelected, true);
            app.ClearAllMenu.Text = 'Clear All';
            app.ClearTourchstonefilesMenu = uimenu(app.ClearMenu);
            app.ClearTourchstonefilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearTourchstonefilesMenuSelected, true);
            app.ClearTourchstonefilesMenu.Text = 'Clear Tourchstone files';
            app.ClearNetlistfilesMenu = uimenu(app.ClearMenu);
            app.ClearNetlistfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearNetlistfilesMenuSelected, true);
            app.ClearNetlistfilesMenu.Text = 'Clear Netlist files';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlPanel.Title = 'Control Panel';
            app.StartFreqEditFieldLabel = uilabel(app.ControlPanel);
            app.StartFreqEditFieldLabel.Text = 'Start Freq';
            app.StartFreqEditField = uieditfield(app.ControlPanel, 'numeric');
            app.StartFreqEditField.Limits = [1 Inf];
            app.StartFreqEditField.ValueChangedFcn = createCallbackFcn(app, @StartFreqEditFieldValueChanged, true);
            app.StartFreqEditField.Value = 1;
            app.EndFreqEditFieldLabel = uilabel(app.ControlPanel);
            app.EndFreqEditFieldLabel.Text = 'End Freq';
            app.EndFreqEditField = uieditfield(app.ControlPanel, 'numeric');
            app.EndFreqEditField.Limits = [1 Inf];
            app.EndFreqEditField.ValueChangedFcn = createCallbackFcn(app, @EndFreqEditFieldValueChanged, true);
            app.EndFreqEditField.Value = 1;
            app.StepsEditFieldLabel = uilabel(app.ControlPanel);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.ControlPanel, 'numeric');
            app.StepsEditField.Limits = [1 Inf];
            app.StepsEditField.ValueChangedFcn = createCallbackFcn(app, @StepsEditFieldValueChanged, true);
            app.StepsEditField.Value = 1;
            app.DropDownStartFreq = uidropdown(app.ControlPanel);
            app.DropDownStartFreq.Items = {'Hz', 'KHz', 'MHz', 'GHz', 'THz'};
            app.DropDownStartFreq.ValueChangedFcn = createCallbackFcn(app, @DropDownStartFreqValueChanged, true);
            app.DropDownStartFreq.Value = 'Hz';
            app.DropDownEndFreq = uidropdown(app.ControlPanel);
            app.DropDownEndFreq.Items = {'Hz', 'KHz', 'MHz', 'GHz', 'THz'};
            app.DropDownEndFreq.ValueChangedFcn = createCallbackFcn(app, @DropDownEndFreqValueChanged, true);
            app.DropDownEndFreq.Value = 'Hz';
            app.RunButton = uibutton(app.ControlPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TouchtoneFileoptionsPanel = uipanel(app.ControlPanel);
            app.TouchtoneFileoptionsPanel.Title = 'Touchtone File options';
            app.OpenButton = uibutton(app.TouchtoneFileoptionsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = fullfile(pathToMLAPP, 'folderIcon.png');
            app.OpenButton.Text = '';
            app.SaveButton = uibutton(app.TouchtoneFileoptionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.InputfileEditFieldLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.InputfileEditFieldLabel.Text = 'Input file';
            app.InputfileEditField = uieditfield(app.TouchtoneFileoptionsPanel, 'text');
            app.InputfileEditField.Editable = 'off';
            app.OutputfileEditFieldLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.OutputfileEditFieldLabel.Text = 'Output file';
            app.OutputfileEditField = uieditfield(app.TouchtoneFileoptionsPanel, 'text');
            app.OutputfileEditField.ValueChangedFcn = createCallbackFcn(app, @OutputfileEditFieldValueChanged, true);
            app.OutputfileEditField.Value = 'parameters';
            app.FormatDropDownLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.FormatDropDownLabel.Text = 'Format';
            app.FormatDropDown = uidropdown(app.TouchtoneFileoptionsPanel);
            app.FormatDropDown.Items = {'RI', 'MA', 'DB'};
            app.FormatDropDown.ValueChangedFcn = createCallbackFcn(app, @FormatDropDownValueChanged, true);
            app.FormatDropDown.Value = 'RI';
            app.FreqUnitDropDownLabel = uilabel(app.TouchtoneFileoptionsPanel);
            app.FreqUnitDropDownLabel.Text = 'Freq Unit';
            app.FreqUnitDropDown = uidropdown(app.TouchtoneFileoptionsPanel);
            app.FreqUnitDropDown.Items = {'Hz', 'KHz', 'MHz', 'GHz'};
            app.FreqUnitDropDown.ValueChangedFcn = createCallbackFcn(app, @FreqUnitDropDownValueChanged, true);
            app.FreqUnitDropDown.Value = 'Hz';
            app.PortnumberEditFieldLabel = uilabel(app.ControlPanel);
            app.PortnumberEditFieldLabel.Text = 'Port number';
            app.PortnumberEditField = uieditfield(app.ControlPanel, 'numeric');
            app.PortnumberEditField.Limits = [1 Inf];
            app.PortnumberEditField.RoundFractionalValues = 'on';
            app.PortnumberEditField.ValueChangedFcn = createCallbackFcn(app, @PortnumberEditFieldValueChanged, true);
            app.PortnumberEditField.Editable = 'off';
            app.PortnumberEditField.Value = 2;
            app.ZoEditFieldLabel = uilabel(app.ControlPanel);
            app.ZoEditFieldLabel.Text = 'Zo';
            app.ZoEditField = uieditfield(app.ControlPanel, 'numeric');
            app.ZoEditField.Limits = [1 Inf];
            app.ZoEditField.ValueChangedFcn = createCallbackFcn(app, @ZoEditFieldValueChanged, true);
            app.ZoEditField.Value = 50;
            app.ZoEditFieldLabel_2 = uilabel(app.ControlPanel);
            app.ZoEditFieldLabel_2.Text = 'Ω';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Name'; '1st Node'; '2nd Node'; 'Type'; 'Value'};
            app.UITable.RowName = {};
            app.TouchstoneFilesLabel = uilabel(app.GridLayout3);
            app.TouchstoneFilesLabel.Text = 'Touchstone Files';
            app.ListBoxTouchstoneFiles = uilistbox(app.GridLayout3);
            app.ListBoxTouchstoneFiles.Items = {};
            app.ListBoxTouchstoneFiles.ValueChangedFcn = createCallbackFcn(app, @ListBoxTouchstoneFilesValueChanged, true);
            app.ListBoxTouchstoneFiles.Value = {};
            app.NetlistFilesLabel = uilabel(app.GridLayout3);
            app.NetlistFilesLabel.Text = 'Netlist Files';
            app.ListBoxNetlistFiles = uilistbox(app.GridLayout3);
            app.ListBoxNetlistFiles.Items = {};
            app.ListBoxNetlistFiles.ValueChangedFcn = createCallbackFcn(app, @ListBoxNetlistFilesValueChanged, true);
            app.ListBoxNetlistFiles.Value = {};
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.RectangularTab = uitab(app.TabGroup);
            app.RectangularTab.Title = 'Rectangular';
            app.GridLayout4 = uigridlayout(app.RectangularTab);
            app.UIAxesS11 = uiaxes(app.GridLayout4);
            title(app.UIAxesS11, 'S11')
            xlabel(app.UIAxesS11, 'Freq')
            ylabel(app.UIAxesS11, 'dB')
            zlabel(app.UIAxesS11, 'Z')
            app.UIAxesS12 = uiaxes(app.GridLayout4);
            title(app.UIAxesS12, 'S12')
            xlabel(app.UIAxesS12, 'Freq')
            ylabel(app.UIAxesS12, 'dB')
            zlabel(app.UIAxesS12, 'Z')
            app.UIAxesS21 = uiaxes(app.GridLayout4);
            title(app.UIAxesS21, 'S21')
            xlabel(app.UIAxesS21, 'Freq')
            ylabel(app.UIAxesS21, 'dB')
            zlabel(app.UIAxesS21, 'Z')
            app.UIAxesS22 = uiaxes(app.GridLayout4);
            title(app.UIAxesS22, 'S22')
            xlabel(app.UIAxesS22, 'Freq')
            ylabel(app.UIAxesS22, 'dB')
            zlabel(app.UIAxesS22, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.PlotSelectorDropDownLabel = uilabel(app.GridLayout5);
            app.PlotSelectorDropDownLabel.Enable = 'off';
            app.PlotSelectorDropDownLabel.Visible = 'off';
            app.PlotSelectorDropDownLabel.Text = 'Plot Selector';
            app.PlotSelectorDropDown = uidropdown(app.GridLayout5);
            app.PlotSelectorDropDown.Items = {'S11 to S22'};
            app.PlotSelectorDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotSelectorDropDownValueChanged, true);
            app.PlotSelectorDropDown.Enable = 'off';
            app.PlotSelectorDropDown.Visible = 'off';
            app.PlotSelectorDropDown.Value = 'S11 to S22';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.PlotStyleDropDownLabel = uilabel(app.GridLayout6);
            app.PlotStyleDropDownLabel.Text = 'Plot Style';
            app.RectPlotStyleDropDown = uidropdown(app.GridLayout6);
            app.RectPlotStyleDropDown.Items = {'Magnitude', 'Phase', 'Real', 'Imaginary'};
            app.RectPlotStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @RectPlotStyleDropDownValueChanged, true);
            app.RectPlotStyleDropDown.Value = 'Magnitude';
            app.PolarTab = uitab(app.TabGroup);
            app.PolarTab.Title = 'Polar';
            app.GridLayout7 = uigridlayout(app.PolarTab);
            app.GridLayout5_3 = uigridlayout(app.GridLayout7);
            app.PlotSelectorDropDown_3Label = uilabel(app.GridLayout5_3);
            app.PlotSelectorDropDown_3Label.Enable = 'off';
            app.PlotSelectorDropDown_3Label.Visible = 'off';
            app.PlotSelectorDropDown_3Label.Text = 'Plot Selector';
            app.PlotSelectorDropDown_3 = uidropdown(app.GridLayout5_3);
            app.PlotSelectorDropDown_3.Items = {'S11 to S22'};
            app.PlotSelectorDropDown_3.Enable = 'off';
            app.PlotSelectorDropDown_3.Visible = 'off';
            app.PlotSelectorDropDown_3.Value = 'S11 to S22';
            app.SmithChartTab = uitab(app.TabGroup);
            app.SmithChartTab.Title = 'Smith Chart';
            app.GridLayout8 = uigridlayout(app.SmithChartTab);
            app.GridLayout5_4 = uigridlayout(app.GridLayout8);
            app.PlotSelectorDropDown_4Label = uilabel(app.GridLayout5_4);
            app.PlotSelectorDropDown_4Label.Enable = 'off';
            app.PlotSelectorDropDown_4Label.Visible = 'off';
            app.PlotSelectorDropDown_4Label.Text = 'Plot Selector';
            app.PlotSelectorDropDown_4 = uidropdown(app.GridLayout5_4);
            app.PlotSelectorDropDown_4.Items = {'S11 to S22'};
            app.PlotSelectorDropDown_4.Enable = 'off';
            app.PlotSelectorDropDown_4.Visible = 'off';
            app.PlotSelectorDropDown_4.Value = 'S11 to S22';
            app.S11SmithChartPanel = uipanel(app.GridLayout8);
            app.S12SmithChartPanel = uipanel(app.GridLayout8);
            app.S21SmithChartPanel = uipanel(app.GridLayout8);
            app.S22SmithChartPanel = uipanel(app.GridLayout8);
            app.CTCTab = uitab(app.TabGroup);
            app.CTCTab.Title = 'CTC';
            app.GridLayout9 = uigridlayout(app.CTCTab);
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ADS touchstone sim.png');
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.SimulateButton = uibutton(app.GridLayout10, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Netlist1ListBoxLabel = uilabel(app.GridLayout10);
            app.Netlist1ListBoxLabel.Text = 'Netlist1';
            app.Netlist1ListBox = uilistbox(app.GridLayout10);
            app.Netlist1ListBox.Items = {'None'};
            app.Netlist1ListBox.ItemsData = {'None'};
            app.Netlist1ListBox.ValueChangedFcn = createCallbackFcn(app, @Netlist1ListBoxValueChanged, true);
            app.Netlist1ListBox.Value = 'None';
            app.Netlist2ListBoxLabel = uilabel(app.GridLayout10);
            app.Netlist2ListBoxLabel.Text = 'Netlist2';
            app.Netlist2ListBox = uilistbox(app.GridLayout10);
            app.Netlist2ListBox.Items = {'None'};
            app.Netlist2ListBox.ItemsData = {'None'};
            app.Netlist2ListBox.ValueChangedFcn = createCallbackFcn(app, @Netlist2ListBoxValueChanged, true);
            app.Netlist2ListBox.Value = 'None';
            app.TouchstoneListBoxLabel = uilabel(app.GridLayout10);
            app.TouchstoneListBoxLabel.Text = 'Touchstone';
            app.TouchstoneListBox = uilistbox(app.GridLayout10);
            app.TouchstoneListBox.Items = {};
            app.TouchstoneListBox.ValueChangedFcn = createCallbackFcn(app, @TouchstoneListBoxValueChanged, true);
            app.TouchstoneListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end